classdef timeComplexityAnalysizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        QuersionTab                matlab.ui.container.Tab
        Image                      matlab.ui.control.Image
        Hyperlink_3                matlab.ui.control.Hyperlink
        EasyLabel_2                matlab.ui.control.Label
        EasyLabel                  matlab.ui.control.Label
        DescriptionLabel           matlab.ui.control.Label
        ProblemListListBox         matlab.ui.control.ListBox
        ProblemListListBoxLabel    matlab.ui.control.Label
        UploadTab                  matlab.ui.container.Tab
        RandomTestcaseButton       matlab.ui.control.Button
        UploadButton               matlab.ui.control.Button
        Hyperlink_2                matlab.ui.control.Hyperlink
        OutputTextArea             matlab.ui.control.TextArea
        OutputTextAreaLabel        matlab.ui.control.Label
        InputTextArea              matlab.ui.control.TextArea
        InputTextAreaLabel         matlab.ui.control.Label
        SourcecodeTextArea_2       matlab.ui.control.TextArea
        SourcecodeTextArea_2Label  matlab.ui.control.Label
        languageDropDown_2         matlab.ui.control.DropDown
        languageDropDown_2Label    matlab.ui.control.Label
        AnalysisTab                matlab.ui.container.Tab
        SystemlogTextArea          matlab.ui.control.TextArea
        SystemlogTextAreaLabel     matlab.ui.control.Label
        submmitedatLabel           matlab.ui.control.Label
        testcaespassedLabel        matlab.ui.control.Label
        StateLabel                 matlab.ui.control.Label
        Hyperlink                  matlab.ui.control.Hyperlink
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            sourceCodeTextContent = app.SourcecodeTextArea_2.Value;
            cppFilePath = fullfile('./source.cpp');
            format longG;
            t = now;
            d = datetime(t,'ConvertFrom','datenum');
            try
                app.submmitedatLabel.Text = "sumbmitted at "+string(d);
                fileID = fopen(cppFilePath, 'w');
                fprintf(fileID, '%s\n', sourceCodeTextContent{:});
                fclose(fileID);
                pyrunfile('compile.py'); % call python script to compile c++ file
                response = pyrunfile('verify.py', 'response');
                tmp = response(1,3);
                logs = tmp{1};
                app.SystemlogTextArea.Value = string(logs);
                tmp = response(1, 1);
                x = tmp{1};
                tmp = response(1, 2);
                y = tmp{1};
                %plot(app.UIAxes, x, y);
                %axis([0 100000 0 1]);
                %hold("on");
                p = polyfit(double(x), double(y), 4);
                t = 0:100:100000;
                nev = polyval(p, t);
                plot(app.UIAxes, t, nev, x, y, '*');
                % axis([0 100000 0 1]);
                tmp = response(1, 4);
                status = tmp{1};
                app.testcaespassedLabel.Text = string(status(1))+"/"+string(status(2))+" testcases passed!"
                uialert(app.UIFigure, 'Text content successfully written to source.cpp.', 'Success', "Icon","success");
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end

        % Button pushed function: RandomTestcaseButton
        function RandomTestcaseButtonPushed(app, event)
            try
                inLines = readlines("./in.txt","EmptyLineRule","skip");
                outLines = readlines("./out.txt","EmptyLineRule","skip");
                spl = size(inLines);
                r = rand(1);
                idx = int32(mod(r*100, spl(1, 1)))+1;
                app.InputTextArea.Value = inLines(idx);
                app.OutputTextArea.Value = outLines(idx);
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.QuersionTab = uitab(app.TabGroup);
            app.QuersionTab.Title = 'Quersion';
            app.ProblemListListBoxLabel = uilabel(app.QuersionTab);
            app.ProblemListListBoxLabel.Text = 'Problem List';
            app.ProblemListListBox = uilistbox(app.QuersionTab);
            app.ProblemListListBox.Items = {'Count Sum', 'Find the Prefix Common Array of Two Arrays', 'Minimize XOR', 'Minimum Length of String After Operations', 'Check if a Parentheses String Can Be Valid', 'Construct K Palindrome Strings', 'Word Subsets', 'Counting Words With a Given Prefix', 'Count Prefix and Suffix Pairs I', 'String Matching in an Array', 'Shifting Letters II', 'Unique Length-3 Palindromic Subsequences', 'Number of Ways to Split Array', 'Count Vowel Strings in Ranges', 'Maximum Score After Splitting a String', 'Minimum Cost For Tickets', 'Count Ways To Build Good Strings', 'Maximum Sum of 3 Non-Overlapping Subarrays', 'Best Sightseeing Pair', 'Target Sum', 'Find Largest Value in Each Tree Row', 'Find Minimum Diameter After Merging Two Trees', 'Find Building Where Alice and Bob Can Meet', 'Maximum Number of K-Divisible Components'};
            app.ProblemListListBox.Value = 'Count Sum';
            app.DescriptionLabel = uilabel(app.QuersionTab);
            app.DescriptionLabel.Text = 'Description';
            app.EasyLabel = uilabel(app.QuersionTab);
            app.EasyLabel.Text = 'Easy';
            app.EasyLabel_2 = uilabel(app.QuersionTab);
            app.EasyLabel_2.Text = {'Write a program that takes an input sequence of'; 'an irregular number of integers and outputs the '; 'sum of the integers in the sequence.'; ''; 'Input:'; 'A single line containing an irregular number '; 'of integers separated by spaces.'; 'Output:'; 'A single integer representing the sum of all the '; 'integers in the input sequence.'; ''; 'Example:'; 'Input:'; '1 2 3 4 5'; ''; 'Output:'; '15'};
            app.Hyperlink_3 = uihyperlink(app.QuersionTab);
            app.Hyperlink_3.URL = 'https://github.com/kiol1812/Time-Complexity-Analysis';
            app.Hyperlink_3.Text = 'help';
            app.Image = uiimage(app.QuersionTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Cat crossing crochet poster tapestry grid.jpg');
            app.UploadTab = uitab(app.TabGroup);
            app.UploadTab.Title = 'Upload';
            app.languageDropDown_2Label = uilabel(app.UploadTab);
            app.languageDropDown_2Label.Text = 'language';
            app.languageDropDown_2 = uidropdown(app.UploadTab);
            app.languageDropDown_2.Items = {'C++', 'C', 'Python', 'Java', 'Rust'};
            app.languageDropDown_2.Value = 'C++';
            app.SourcecodeTextArea_2Label = uilabel(app.UploadTab);
            app.SourcecodeTextArea_2Label.Text = 'Source code';
            app.SourcecodeTextArea_2 = uitextarea(app.UploadTab);
            app.SourcecodeTextArea_2.Value = {'// write your solution here.'};
            app.InputTextAreaLabel = uilabel(app.UploadTab);
            app.InputTextAreaLabel.Text = 'Input';
            app.InputTextArea = uitextarea(app.UploadTab);
            app.OutputTextAreaLabel = uilabel(app.UploadTab);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.UploadTab);
            app.Hyperlink_2 = uihyperlink(app.UploadTab);
            app.Hyperlink_2.URL = 'https://github.com/kiol1812/Time-Complexity-Analysis';
            app.Hyperlink_2.Text = 'help';
            app.UploadButton = uibutton(app.UploadTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload!';
            app.RandomTestcaseButton = uibutton(app.UploadTab, 'push');
            app.RandomTestcaseButton.ButtonPushedFcn = createCallbackFcn(app, @RandomTestcaseButtonPushed, true);
            app.RandomTestcaseButton.Text = 'Random Testcase';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.UIAxes = uiaxes(app.AnalysisTab);
            title(app.UIAxes, 'Result')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Hyperlink = uihyperlink(app.AnalysisTab);
            app.Hyperlink.URL = 'https://github.com/kiol1812/Time-Complexity-Analysis';
            app.Hyperlink.Text = 'help';
            app.StateLabel = uilabel(app.AnalysisTab);
            app.StateLabel.Text = 'State';
            app.testcaespassedLabel = uilabel(app.AnalysisTab);
            app.testcaespassedLabel.Text = 'testcaes passed';
            app.submmitedatLabel = uilabel(app.AnalysisTab);
            app.submmitedatLabel.Text = 'submmited at';
            app.SystemlogTextAreaLabel = uilabel(app.AnalysisTab);
            app.SystemlogTextAreaLabel.Text = 'System log';
            app.SystemlogTextArea = uitextarea(app.AnalysisTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = timeComplexityAnalysizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end